classdef streamlineInteract < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        cSlider                   matlab.ui.control.Slider
        cSliderLabel              matlab.ui.control.Label
        p_optSlider               matlab.ui.control.Slider
        p_optSliderLabel          matlab.ui.control.Label
        pdependenceDropDown       matlab.ui.control.DropDown
        pdependenceDropDownLabel  matlab.ui.control.Label
        fSlider                   matlab.ui.control.Slider
        fSliderLabel              matlab.ui.control.Label
        bSlider                   matlab.ui.control.Slider
        bSliderLabel              matlab.ui.control.Label
        lambdaSlider              matlab.ui.control.Slider
        lambdaSliderLabel         matlab.ui.control.Label
        KSlider                   matlab.ui.control.Slider
        KSliderLabel              matlab.ui.control.Label
        alphaSlider               matlab.ui.control.Slider
        alphaSliderLabel          matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        alpha = 0.1; % Description
        K = 0.5;
        lambda = 0;
        b = 0.1;
        f = 0.1;
        gamma_option = 0;
        p_opt = 0;
        c=0;
        TrajectoryPlot = []; %single trajectory on the streamline
        ClickedPointLabel = [];
    end
    
    methods (Access = private)
        
        % Define the rates function
        % Define the Gamma_p function
        % Gamma_p = @(p, p_opt, pc, sig2) 1 - (1 - exp(-(p - p_opt).^2 / (2*sig2))) ./ (1 - exp(-pc^2 / (2*sig2)));
        % Gamma_p_linear = @(p, p_opt, pc, sig2) 1 - abs(p-p_opt)/sig2;
        function gamma = Gamma_p(app, p, p_opt, pc, sig2) 
            gamma = 1 - (1 - exp(-(p - p_opt).^2 / (2*sig2))) ./ (1 - exp(-pc^2 / (2*sig2)));
        end

        function gamma = Gamma_p_linear(app, p, p_opt, pc, sig2)
            gamma = 1 - abs(p-p_opt)/sig2;
        end
        
        function rate = rates(app,n,p, p_opt)
            rate = [app.alpha * n .* (1 - n./app.K + app.Gamma_p(p,p_opt,0.6,1)) + app.lambda;
            app.alpha * app.b * (app.alpha * n .* (1 - n./app.K + app.Gamma_p(p,p_opt,0.6,1)) ...
            + app.lambda) .* double(app.alpha * n .* (1 - n./app.K + app.Gamma_p(p,p_opt,0.6,1)) + app.lambda > 0) ...
            + app.c*n - app.f * p];
        end

        function rate = rates_linear(app, n, p, p_opt)
            rate = [app.alpha * n .* (1 - n./app.K + app.Gamma_p_linear(p,p_opt,0.6,1)) + app.lambda;
             app.alpha * app.b * (app.alpha * n .* (1 - n./app.K + app.Gamma_p_linear(p,p_opt,0.6,1)) + app.lambda) ...
             .* double(app.alpha * n .* (1 - n./app.K + app.Gamma_p_linear(p,p_opt,0.6,1)) + app.lambda > 0) ...
             +app.c*n - app.f * p];
        end
        
        % Function to update the plot
        function updatePlot(app)
            % Clear the previous plot
            cla(app.UIAxes);


            % alpha = app.alpha;
            % Define the rates function
            % rates = @(n, p) [app.alpha * n .* (1 - n./app.K + Gamma_p(p,0.,0.6,1)) + app.lambda; 
            %                  app.alpha * app.b * (app.alpha * n .* (1 - n./app.K + Gamma_p(p,0.,0.6,1)) + app.lambda) .* double(app.alpha * n .* (1 - n./app.K + Gamma_p(p,0.,0.6,1)) + app.lambda > 0) - app.f * p];
            % 
            % rates_linear = @(n,p) [app.alpha * n .* (1 - n./app.K + Gamma_p_linear(p,0.,0.6,1)) + app.lambda; 
            %                  app.alpha * app.b * (app.alpha * n .* (1 - n./app.K + Gamma_p_linear(p,0.,0.6,1)) + app.lambda) .* double(app.alpha * n .* (1 - n./app.K + Gamma_p_linear(p,0.,0.6,1)) + app.lambda > 0) - app.f * p];
            % Generate the grid of points
            [n_values, p_values] = meshgrid(0:0.05:5, -2:0.05:2);
            
            % Calculate the rates at each point on the grid
            U = zeros(size(n_values));
            V = zeros(size(p_values));
            for i = 1:numel(n_values)
                if app.gamma_option == 0
                    rates_i = app.rates(n_values(i), p_values(i), app.p_opt);
                else
                    rates_i = app.rates_linear(n_values(i),p_values(i), app.p_opt);
                end
                U(i) = rates_i(1);
                V(i) = rates_i(2);
            end

            % Normalize the arrows (optional)
            mags = sqrt(U.^2 + V.^2);
            U = U ./ mags;
            V = V ./ mags;

            streamslice(app.UIAxes, n_values, p_values, U, V);
        end
        
        % Callback function for clicks on the axes
        function axesClickCallback(app, event)
            clickPos = app.UIAxes.CurrentPoint;  % Get the current mouse position
            n0 = clickPos(1,1);
            p0 = clickPos(1,2);
            % Remove the previous clicked point label if it exists
            if ~isempty(app.ClickedPointLabel) && isvalid(app.ClickedPointLabel)
                delete(app.ClickedPointLabel);
            end
            
            % Label the clicked point
            app.ClickedPointLabel = text(app.UIAxes, n0, p0, sprintf('(%0.2f,%0.2f)', n0, p0), 'VerticalAlignment', 'bottom');
    
            % Run the simulation with n0 and p0 as initial conditions
            if app.gamma_option == 0
                [t, Y] = ode45(@(t, Y) app.rates(Y(1), Y(2), app.p_opt), [0 120], [n0; p0]);
            else 
                [t, Y] = ode45(@(t, Y) app.rates_linear(Y(1), Y(2), app.p_opt), [0 120], [n0; p0]);
            end
            % Plot the result on the second axes
            plot(app.UIAxes2, t, Y);
                    
            % Delete the previous trajectory
            if ~isempty(app.TrajectoryPlot) && isvalid(app.TrajectoryPlot)
                delete(app.TrajectoryPlot)
            end
                    
            % Plot the trajectory on the first axes
            current_hold_state = ishold(app.UIAxes);  % Save the current hold state
            hold(app.UIAxes, 'on');  % Hold the axes to overlay the plot
            app.TrajectoryPlot = plot(app.UIAxes, Y(:,1), Y(:,2), 'r-');  % Plot the trajectory
            if ~current_hold_state
                hold(app.UIAxes, 'off') ;  % Restore the hold state
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: alphaSlider
        function alphaSliderValueChanged(app, event)
            value = app.alphaSlider.Value;
            app.alpha = value;  % Update the parameter
            updatePlot(app);  % Redraw the plot
        end

        % Value changed function: KSlider
        function KSliderValueChanged(app, event)
            value = app.KSlider.Value;
            app.K = value;  % Update the parameter
            updatePlot(app);  % Redraw the plot
        end

        % Value changed function: lambdaSlider
        function lambdaSliderValueChanged(app, event)
            value = app.lambdaSlider.Value;
            app.lambda = value;  % Update the parameter
            updatePlot(app);  % Redraw the plot
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            value = app.bSlider.Value;
            app.b = value;  % Update the parameter
            updatePlot(app);  % Redraw the plot
        end

        % Value changed function: fSlider
        function fSliderValueChanged(app, event)
            value = app.fSlider.Value;
            app.f = value;  % Update the parameter
            updatePlot(app);  % Redraw the plot
        end

        % Value changed function: pdependenceDropDown
        function pdependenceDropDownValueChanged(app, event)
            value = app.pdependenceDropDown.Value;
            app.gamma_option = value;
            updatePlot(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.axesClickCallback(event);
        end

        % Value changed function: p_optSlider
        function p_optSliderValueChanged(app, event)
            value = app.p_optSlider.Value;
            app.p_opt = value;
            updatePlot(app);
        end

        % Value changed function: cSlider
        function cSliderValueChanged(app, event)
            value = app.cSlider.Value;
            app.c = value;
            updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Streamline')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'p')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 5];
            app.UIAxes.YLim = [-2 2];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'dynamics')
            xlabel(app.UIAxes2, 't')
            zlabel(app.UIAxes2, 'Z')
            app.alphaSliderLabel = uilabel(app.UIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.UIFigure);
            app.alphaSlider.Limits = [0.1 10];
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @alphaSliderValueChanged, true);
            app.alphaSlider.Value = 0.1;
            app.KSliderLabel = uilabel(app.UIFigure);
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.UIFigure);
            app.KSlider.Limits = [0.1 10];
            app.KSlider.ValueChangedFcn = createCallbackFcn(app, @KSliderValueChanged, true);
            app.KSlider.Value = 0.5;
            app.lambdaSliderLabel = uilabel(app.UIFigure);
            app.lambdaSliderLabel.Text = 'lambda';
            app.lambdaSlider = uislider(app.UIFigure);
            app.lambdaSlider.Limits = [0 1];
            app.lambdaSlider.ValueChangedFcn = createCallbackFcn(app, @lambdaSliderValueChanged, true);
            app.lambdaSlider.MinorTicks = [0 0.02035 0.0407 0.06105 0.0814 0.10175 0.1221 0.14245 0.1628 0.18315 0.2035 0.22385 0.2442 0.26455 0.2849 0.30525 0.3256 0.34595 0.3663 0.38665 0.407 0.42735 0.4477 0.46805 0.4884 0.50875 0.5291 0.54945 0.5698 0.59015 0.6105 0.63085 0.6512 0.67155 0.6919 0.71225 0.7326 0.75295 0.7733 0.79365 0.814 0.83435 0.8547 0.87505 0.8954 0.91575 0.9361 0.95645 0.9768 1 10];
            app.lambdaSlider.Value = 0.5;
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [-10 10];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.fSliderLabel = uilabel(app.UIFigure);
            app.fSliderLabel.Text = 'f';
            app.fSlider = uislider(app.UIFigure);
            app.fSlider.Limits = [0 10];
            app.fSlider.ValueChangedFcn = createCallbackFcn(app, @fSliderValueChanged, true);
            app.pdependenceDropDownLabel = uilabel(app.UIFigure);
            app.pdependenceDropDownLabel.Text = 'p-dependence';
            app.pdependenceDropDown = uidropdown(app.UIFigure);
            app.pdependenceDropDown.Items = {'Gamma', 'Linear Gamma'};
            app.pdependenceDropDown.ItemsData = [0 1];
            app.pdependenceDropDown.ValueChangedFcn = createCallbackFcn(app, @pdependenceDropDownValueChanged, true);
            app.pdependenceDropDown.Value = 0;
            app.p_optSliderLabel = uilabel(app.UIFigure);
            app.p_optSliderLabel.Text = 'p_opt';
            app.p_optSlider = uislider(app.UIFigure);
            app.p_optSlider.Limits = [-1 1];
            app.p_optSlider.ValueChangedFcn = createCallbackFcn(app, @p_optSliderValueChanged, true);
            app.cSliderLabel = uilabel(app.UIFigure);
            app.cSliderLabel.Text = 'c';
            app.cSlider = uislider(app.UIFigure);
            app.cSlider.Limits = [-10 10];
            app.cSlider.ValueChangedFcn = createCallbackFcn(app, @cSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = streamlineInteract

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end